classdef xcorr_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Sref                      matlab.ui.control.UIAxes
        Sr                        matlab.ui.control.UIAxes
        xcorr                     matlab.ui.control.UIAxes
        SignalButtonGroup         matlab.ui.container.ButtonGroup
        WhiteGaussianNoiseButton  matlab.ui.control.RadioButton
        PlotButton                matlab.ui.control.Button
        SamplesNEditFieldLabel    matlab.ui.control.Label
        SamplesNEditField         matlab.ui.control.NumericEditField
        DelaytimelEditFieldLabel  matlab.ui.control.Label
        DelaytimelEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PlotButton, SignalButtonGroup
        function PlotButtonPushed(app, event)
            
            %Set a random white gaussian noise for signal and set a delay for signal 2
            val = 1;
            switch val
            case 1
                signal = wgn(app.SamplesNEditField.Value,1,0);
                %signal 2 will be the shifted signal horizontally by the
                %Delay-time value
                signal2 = circshift(signal,app.DelaytimelEditField.Value);
                
            end
            
            %Set signal to Sref graph
            plot(app.Sref,[1:app.SamplesNEditField.Value],signal)
            %Set signal2 to Sr graph
            plot(app.Sr,[1:app.SamplesNEditField.Value]+app.DelaytimelEditField.Value,signal)
            
            %shift_vector = 1:app.SamplesNEditField.Value;
            
            %Cross Correlation between Sref and Sr
            [xcorrs, lag] = xcorr(signal,signal2);
            
            %Plot xcorr Graph
            plot(app.xcorr,lag,xcorrs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Sref = uiaxes(app.UIFigure);
            title(app.Sref, 'Reference Signal')
            xlabel(app.Sref, 'X')
            ylabel(app.Sref, 'Y')
            app.Sr = uiaxes(app.UIFigure);
            title(app.Sr, 'Surveillance Signal')
            xlabel(app.Sr, 'X')
            ylabel(app.Sr, 'Y')
            app.xcorr = uiaxes(app.UIFigure);
            title(app.xcorr, 'Cross Correlation')
            xlabel(app.xcorr, 'Lag')
            ylabel(app.xcorr, 'Y')
            app.SignalButtonGroup = uibuttongroup(app.UIFigure);
            app.SignalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SignalButtonGroup.Title = 'Signal';
            app.WhiteGaussianNoiseButton = uiradiobutton(app.SignalButtonGroup);
            app.WhiteGaussianNoiseButton.Text = 'White Gaussian Noise';
            app.WhiteGaussianNoiseButton.Value = true;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SamplesNEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesNEditFieldLabel.Text = 'Samples (N)';
            app.SamplesNEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplesNEditField.Value = 1000;
            app.DelaytimelEditFieldLabel = uilabel(app.UIFigure);
            app.DelaytimelEditFieldLabel.Text = 'Delay-time (l)';
            app.DelaytimelEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelaytimelEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xcorr_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end